classdef PGG327FinalExam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab1_CaseDefinition             matlab.ui.container.Tab
        AssumptionsTextArea             matlab.ui.control.TextArea
        EquipmentLimitsTextArea         matlab.ui.control.TextArea
        HookLoadOffsetValueLabel        matlab.ui.control.Label
        FrictionFactorValueLabel        matlab.ui.control.Label
        MudWeightValueLabel             matlab.ui.control.Label
        AssumptionsTextAreaLabel        matlab.ui.control.Label
        HookLoadOffsetLabel             matlab.ui.control.Label
        EquipmentLimitsLabel            matlab.ui.control.Label
        FrictionFactorLabel             matlab.ui.control.Label
        MudWeightLabel                  matlab.ui.control.Label
        SerialNumberEditField           matlab.ui.control.NumericEditField
        SerialNumberEditFieldLabel      matlab.ui.control.Label
        Tab2_SurveyValidation           matlab.ui.container.Tab
        SurveyDataLabel                 matlab.ui.control.Label
        ValidationLogTextArea           matlab.ui.control.TextArea
        ValidationStatusLabel           matlab.ui.control.Label
        SurveyTable                     matlab.ui.control.Table
        ValidateSurveyButton            matlab.ui.control.Button
        LoadSurveyButton                matlab.ui.control.Button
        Tab3_Wellpath                   matlab.ui.container.Tab
        TrajectoryTable                 matlab.ui.control.Table
        TrajectoryStatusValueLabel      matlab.ui.control.Label
        TrajectoryStatusLabel           matlab.ui.control.Label
        CalculateWellpathButton         matlab.ui.control.Button
        Wellpath3DUIAxes                matlab.ui.control.UIAxes
        Tab4_TorqueDrag                 matlab.ui.container.Tab
        SurfaceValuesLabel              matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        DragTorqueAxes                  matlab.ui.control.UIAxes
        PickupSlackoffAxes              matlab.ui.control.UIAxes
        Tab5_TelemetryDashboard         matlab.ui.container.Tab
        RunStageButton                  matlab.ui.control.Button
        CurrentStageLabel               matlab.ui.control.Label
        OperationalTrendAxes            matlab.ui.control.UIAxes
        MechanicalTrendAxes             matlab.ui.control.UIAxes
        Tab6_QCDecision                 matlab.ui.container.Tab
        QCResultsTable                  matlab.ui.control.Table
        ControllingTriggerLabel         matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        UtilizationBarAxes              matlab.ui.control.UIAxes
        Tab7_StageDecisionLog           matlab.ui.container.Tab
        DecisionLogTable                matlab.ui.control.Table
        NextStageButton                 matlab.ui.control.Button
        DecisionStatusLabel             matlab.ui.control.Label
        ConfirmStageButton              matlab.ui.control.Button
        WhatevidencedoyourequirenextLabel  matlab.ui.control.Label
        EvidenceNeededNextTextArea      matlab.ui.control.TextArea
        WhatactionescalationdoyourecommendLabel  matlab.ui.control.Label
        RecommendedActionTextArea       matlab.ui.control.TextArea
        WhatuncertaintyremainsLabel     matlab.ui.control.Label
        UncertaintyTextArea             matlab.ui.control.TextArea
        WhatstatusdoyouassignandwhyLabel  matlab.ui.control.Label
        AssignedStatusTextArea          matlab.ui.control.TextArea
        WhichcalculationmeasurementcontrolstheriskLabel  matlab.ui.control.Label
        ControllingFactorTextArea       matlab.ui.control.TextArea
        WhatChangedTextArea             matlab.ui.control.TextArea
        WhatchangedLabel                matlab.ui.control.Label
        CurrentDecisionStageLabel       matlab.ui.control.Label
        Tab8_Sensitivity                matlab.ui.container.Tab
        AdditionalUncertainty5StringWeightLabel  matlab.ui.control.Label
        EngineeringInterpretationLabel  matlab.ui.control.Label
        SensitivityInterpretationTextArea  matlab.ui.control.TextArea
        UncertaintyResultsTable         matlab.ui.control.Table
        SensitivityResultsTable         matlab.ui.control.Table
        RunSensitivityButton            matlab.ui.control.Button
        SensitivityTitleLabel           matlab.ui.control.Label
        FrictionTorqueAxes              matlab.ui.control.UIAxes
        FrictionLoadAxes                matlab.ui.control.UIAxes
        Tab9_ExportAudit                matlab.ui.container.Tab
        ExportDecisionLogButton         matlab.ui.control.Button
        ExportQCButton                  matlab.ui.control.Button
        ExportFullReportButton          matlab.ui.control.Button
        RefreshAuditButton              matlab.ui.control.Button
        AuditResultsTable               matlab.ui.control.Table
        AuditStatusLabel                matlab.ui.control.Label
        ExportAuditTitleLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        BaseParams
        CaseDef
        SurveyRaw
        SurveyValid
        ValidationLog
        Trajectory
        TDResults
        Telemetry
        QCResults
        DecisionLog
        CurrentStage = 1
    end
    
    methods (Access = private)
        
        function updateTorqueDragPlots(app)

            % =========================================================
            % PICKUP / SLACK-OFF HOOK LOAD
            % =========================================================
        
            cla(app.PickupSlackoffAxes);
        
            hold(app.PickupSlackoffAxes, 'on');
        
            plot(app.PickupSlackoffAxes, ...
                app.TDResults.MD_ft, ...
                app.TDResults.HL_PU_lb ./ 1000, ...
                '-o', ...
                'DisplayName', 'Pickup');
        
            plot(app.PickupSlackoffAxes, ...
                app.TDResults.MD_ft, ...
                app.TDResults.HL_SO_lb ./ 1000, ...
                '-s', ...
                'DisplayName', 'Slack-off');
        
            hold(app.PickupSlackoffAxes, 'off');
        
            grid(app.PickupSlackoffAxes, 'on');
        
            xlabel(app.PickupSlackoffAxes, 'Measured Depth (ft)');
            ylabel(app.PickupSlackoffAxes, 'Hook Load (kips)');
        
            title(app.PickupSlackoffAxes, ...
                'Pickup / Slack-off Hook Load');
        
            legend(app.PickupSlackoffAxes, ...
                'Location', 'best');
        
        
            % =========================================================
            % CUMULATIVE DRAG AND TORQUE
            % =========================================================
        
            cla(app.DragTorqueAxes);
        
            yyaxis(app.DragTorqueAxes, 'left');
        
            plot(app.DragTorqueAxes, ...
                app.TDResults.MD_ft, ...
                app.TDResults.cumDrag_lb, ...
                '-o');
        
            ylabel(app.DragTorqueAxes, ...
                'Cumulative Drag (lb)');
        
        
            yyaxis(app.DragTorqueAxes, 'right');
        
            plot(app.DragTorqueAxes, ...
                app.TDResults.MD_ft, ...
                app.TDResults.cumTorque_ftlbf, ...
                '-s');
        
            ylabel(app.DragTorqueAxes, ...
                'Cumulative Torque (ft-lbf)');
        
            xlabel(app.DragTorqueAxes, ...
                'Measured Depth (ft)');
        
            title(app.DragTorqueAxes, ...
                'Cumulative Drag & Torque');
        
            grid(app.DragTorqueAxes, 'on');
        
        
            % =========================================================
            % SURFACE VALUES
            % =========================================================
        
            surfacePU = app.TDResults.HL_PU_lb(end) / 1000;
            surfaceSO = app.TDResults.HL_SO_lb(end) / 1000;
        
            surfaceDrag = app.TDResults.cumDrag_lb(end);
            surfaceTorque = app.TDResults.cumTorque_ftlbf(end);
        
            app.SurfaceValuesLabel.Text = sprintf( ...
                ['Surface values - Pickup: %.1f kips | ' ...
                 'Slack-off: %.1f kips | ' ...
                 'Drag: %.0f lb | ' ...
                 'Torque: %.0f ft-lbf'], ...
                surfacePU, ...
                surfaceSO, ...
                surfaceDrag, ...
                surfaceTorque);
        
        end    


        function updateQCDecisionDisplay(app)

            % ============================================================
            % CHECK QC RESULTS
            % ============================================================
        
            if isempty(app.QCResults)
                return
            end
        
        
            % ============================================================
            % UPDATE QC RESULTS TABLE
            %
            % Show ALL QC results collected so far
            % ============================================================
        
            app.QCResultsTable.Data = app.QCResults;
        
            app.QCResultsTable.ColumnName = { ...
                'Stage', ...
                'Hook Load Utilization', ...
                'Torque Utilization', ...
                'SPP Utilization', ...
                'Maximum Utilization', ...
                'Status', ...
                'Controlling Trigger'};
        
        
            % ============================================================
            % GET QC RESULT FOR CURRENT STAGE
            %
            % The table above shows ALL stages.
            % The indicators below show ONLY the current stage.
            % ============================================================
        
            currentStage = app.CurrentStage;
        
            currentRow = app.QCResults.Stage == currentStage;
        
            if ~any(currentRow)
        
                % No QC has been run for the current stage yet.
                return
        
            end
        
            qc = app.QCResults(currentRow, :);
        
        
            % ============================================================
            % UPDATE CONTROLLING TRIGGER
            % ============================================================
        
            app.ControllingTriggerLabel.Text = ...
                char(qc.ControllingTrigger);
        
        
            % ============================================================
            % UPDATE STATUS
            % ============================================================
        
            status = char(qc.Status);
        
            switch status
        
                case 'SAFE'
        
                    app.StatusLamp.Color = [0 1 0];
                    app.StatusLampLabel.Text = 'SAFE';
        
                case 'CAUTION'
        
                    app.StatusLamp.Color = [1 1 0];
                    app.StatusLampLabel.Text = 'CAUTION';
        
                case 'CRITICAL'
        
                    app.StatusLamp.Color = [1 0 0];
                    app.StatusLampLabel.Text = 'CRITICAL';
        
            end
        
        
            % ============================================================
            % UPDATE UTILIZATION BAR
            % ============================================================
        
            cla(app.UtilizationBarAxes);
        
            utilization = [ ...
                qc.U_H
                qc.U_T
                qc.U_P
            ];
        
            bar(app.UtilizationBarAxes, utilization);
        
            app.UtilizationBarAxes.XTick = 1:3;
        
            app.UtilizationBarAxes.XTickLabel = { ...
                'Hook Load', ...
                'Torque', ...
                'SPP'};
        
            app.UtilizationBarAxes.YLabel.String = ...
                'Utilization';
        
            app.UtilizationBarAxes.Title.String = ...
                sprintf('Stage %d Utilization', qc.Stage);
        
            app.UtilizationBarAxes.YLim = ...
                [0 max(1.1, max(utilization) * 1.1)];
        
            grid(app.UtilizationBarAxes, 'on');
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % =========================================================
            % PGG327 FINAL EXAM APPLICATION - STARTUP
            % =========================================================
        
            % ---------------------------------------------------------
            % 1. LOAD BASE PARAMETERS
            % ---------------------------------------------------------
            app.BaseParams = getBaseParameters();
        
            % ---------------------------------------------------------
            % 2. GET STUDENT SERIAL NUMBER
            % ---------------------------------------------------------
            n = getStudentSerialNumber();
        
            % ---------------------------------------------------------
            % 3. BUILD PERSONALIZED CASE DEFINITION
            % ---------------------------------------------------------
            app.CaseDef = buildCaseDefinition(n, app.BaseParams);
        
            % ---------------------------------------------------------
            % 4. DISPLAY STUDENT SERIAL NUMBER
            % ---------------------------------------------------------
            app.SerialNumberEditField.Value = app.CaseDef.SerialNumber;
        
            % ---------------------------------------------------------
            % 5. DISPLAY PERSONALIZED MUD WEIGHT
            % ---------------------------------------------------------
            app.MudWeightValueLabel.Text = ...
                sprintf('%.2f ppg', app.CaseDef.MW_ppg);
        
            % ---------------------------------------------------------
            % 6. DISPLAY PERSONALIZED FRICTION FACTOR
            % ---------------------------------------------------------
            app.FrictionFactorValueLabel.Text = ...
                sprintf('%.4f', app.CaseDef.mu);
        
            % ---------------------------------------------------------
            % 7. DISPLAY HOOK LOAD OFFSET
            % ---------------------------------------------------------
            app.HookLoadOffsetValueLabel.Text = ...
                sprintf('+%.0f kips', app.CaseDef.HLOffset_kips);
        
            % ---------------------------------------------------------
            % 8. DISPLAY EQUIPMENT LIMITS
            % ---------------------------------------------------------
            app.EquipmentLimitsTextArea.Value = {
                sprintf('Hook Load Limit: %.0f kips', ...
                    app.CaseDef.HLlimit_kips)
        
                sprintf('Torque Limit: %.0f ft-lbf', ...
                    app.CaseDef.Tlimit_ftlbf)
        
                sprintf('Standpipe Pressure Limit: %.0f psi', ...
                    app.CaseDef.SPPlimit_psi)
            };
        
            % ---------------------------------------------------------
            % 9. DISPLAY ASSUMPTIONS
            % ---------------------------------------------------------
            app.AssumptionsTextArea.Value = {
                'Case Definition traceability panel (Section 4.3).'
                ''
                'Serial number personalizes:'
                '• Mud weight'
                '• Friction factor'
                '• Hook-load telemetry offset'
                ''
                'Equipment limits remain unchanged by personalization.'
                ''
                'Torque-and-drag model is a first-order educational model.'
                'It does not include buckling, stiffness, curvature effects,'
                'or contact redistribution.'
            };
        
            % ---------------------------------------------------------
            % 10. INITIALIZE PERSONALIZED TELEMETRY
            % ---------------------------------------------------------
            app.Telemetry = applyPersonalizedTelemetry( ...
                getExaminationTelemetry(), ...
                app.CaseDef.HLOffset_kips);
        
            % ---------------------------------------------------------
            % 11. INITIALIZE QC RESULTS
            % ---------------------------------------------------------
            app.QCResults = initQCResultsTemplate();
        
            % ---------------------------------------------------------
            % 12. INITIALIZE DECISION LOG
            % ---------------------------------------------------------
            app.DecisionLog = initDecisionLogTemplate();
        
            % ---------------------------------------------------------
            % 13. START AT STAGE 1
            % ---------------------------------------------------------
            app.CurrentStage = 1;
        end

        % Button pushed function: LoadSurveyButton
        function LoadSurveyButtonPushed(app, event)
            % Load the examination survey
            app.SurveyRaw = getExaminationSurvey();
        
            % Display the raw survey in the table
            app.SurveyTable.Data = app.SurveyRaw;
        
            % Display the variable names as table column headings
            app.SurveyTable.ColumnName = app.SurveyRaw.Properties.VariableNames;
        
            % Update validation status
            app.ValidationStatusLabel.Text = 'Survey Loaded';
        
            % Display a message in the validation log
            app.ValidationLogTextArea.Value = {
                'Examination survey loaded successfully.'
                sprintf('Number of survey stations: %d', height(app.SurveyRaw))
                'Variables: MD_ft, Inc_deg, Az_deg'
                'Survey is ready for validation.'
            };
        end

        % Button pushed function: ValidateSurveyButton
        function ValidateSurveyButtonPushed(app, event)
            % =========================================================
            % VALIDATE SURVEY
            % =========================================================
        
            % ---------------------------------------------------------
            % 1. Make sure a survey has been loaded first
            % ---------------------------------------------------------
            if isempty(app.SurveyRaw)
        
                app.ValidationStatusLabel.Text = 'No Survey Loaded';
        
                app.ValidationLogTextArea.Value = {
                    'Validation cannot be performed.'
                    ''
                    'Please click "Load Examination Survey" first.'
                };
        
                return
            end
        
            % ---------------------------------------------------------
            % 2. Run the examination validation engine
            % ---------------------------------------------------------
            [cleanSurvey, log] = validateSurvey(app.SurveyRaw);
        
            % ---------------------------------------------------------
            % 3. Store the validation outputs
            % ---------------------------------------------------------
            app.SurveyValid = cleanSurvey;
            app.ValidationLog = log;
        
            % ---------------------------------------------------------
            % 4. Determine whether validation passed or failed
            % ---------------------------------------------------------
            if isempty(log)
        
                % No validation issues were found
                app.ValidationStatusLabel.Text = 'PASS';
        
                app.ValidationLogTextArea.Value = {
                    'Survey validation PASSED.'
                    ''
                    sprintf('Valid survey stations: %d', height(cleanSurvey))
                    sprintf('Rejected survey stations: %d', ...
                        height(app.SurveyRaw) - height(cleanSurvey))
                    ''
                    'No validation issues were found.'
                    'Survey is ready for trajectory calculation.'
                };
        
            else
        
                % One or more validation issues were found
                app.ValidationStatusLabel.Text = 'FAIL';
        
                % Display the clean/accepted survey
                app.SurveyTable.Data = cleanSurvey;
        
                % Convert validation log table to readable text
                logText = strings(height(log), 1);
        
                for i = 1:height(log)
        
                    logText(i) = sprintf( ...
                        'Row %d | Field: %s | %s | Action: %s', ...
                        log.Row(i), ...
                        string(log.Field(i)), ...
                        string(log.Issue(i)), ...
                        string(log.Action(i)));
        
                end
        
                app.ValidationLogTextArea.Value = [
                    "Survey validation FAILED."
                    ""
                    sprintf('Valid survey stations: %d', height(cleanSurvey))
                    sprintf('Rejected survey stations: %d', ...
                        height(app.SurveyRaw) - height(cleanSurvey))
                    ""
                    "Validation issues:"
                    logText
                ];
        
            end
        end

        % Button pushed function: CalculateWellpathButton
        function CalculateWellpathButtonPushed(app, event)
            % =========================================================
            % WELL PATH CALCULATION
            % =========================================================
        
            % ---------------------------------------------------------
            % 1. Make sure the survey has been loaded
            % ---------------------------------------------------------
            if isempty(app.SurveyRaw)
        
                app.TrajectoryStatusValueLabel.Text = ...
                    'No survey loaded.';
        
                uialert(app.UIFigure, ...
                    'Please load the examination survey first.', ...
                    'Survey Required');
        
                return
            end
        
        
            % ---------------------------------------------------------
            % 2. Make sure the survey has been validated
            % ---------------------------------------------------------
            if isempty(app.SurveyValid)
        
                app.TrajectoryStatusValueLabel.Text = ...
                    'Survey has not been validated.';
        
                uialert(app.UIFigure, ...
                    'Please validate the survey successfully before calculating the wellpath.', ...
                    'Validation Required');
        
                return
            end
        
        
            % ---------------------------------------------------------
            % 3. Calculate the trajectory using minimum curvature
            % ---------------------------------------------------------
            try
        
                trajectory = computeMinimumCurvature(app.SurveyValid);
        
            catch ME
        
                app.TrajectoryStatusValueLabel.Text = ...
                    'Trajectory calculation failed.';
        
                uialert(app.UIFigure, ...
                    ME.message, ...
                    'Wellpath Calculation Error');
        
                return
        
            end
        
        
            % ---------------------------------------------------------
            % 4. Store the trajectory in the app
            % ---------------------------------------------------------
            app.Trajectory = trajectory;
        
        
            % ---------------------------------------------------------
            % 5. Display trajectory table
            % ---------------------------------------------------------
            app.TrajectoryTable.Data = trajectory;
            app.TrajectoryTable.ColumnName = trajectory.Properties.VariableNames;
        
            % ---------------------------------------------------------
            % 6. Plot the 3D wellpath
            % ---------------------------------------------------------
            
            ax = app.Wellpath3DUIAxes;
            
            % Clear previous plot
            cla(ax);
            
            % =========================================================
            % DISPLAY COORDINATES
            % =========================================================
            
            E = trajectory.E_ft;
            N = trajectory.N_ft;
            TVD = -trajectory.TVD_ft;
            
            % =========================================================
            % AXES APPEARANCE
            % =========================================================
            
            % Dark plotting background
            ax.Color = [0.03 0.03 0.03];
            
            % White axis labels/ticks
            ax.XColor = [1 1 1];
            ax.YColor = [1 1 1];
            ax.ZColor = [1 1 1];
            
            % Grid
            grid(ax, 'on');
            
            ax.GridColor = [0.45 0.45 0.45];
            ax.GridAlpha = 0.45;
            
            % =========================================================
            % FULL WELLPATH
            % =========================================================
            
            hold(ax, 'on');
            
            % Complete trajectory in white
            plot3(ax, ...
                E, ...
                N, ...
                TVD, ...
                '-', ...
                'Color', [1 1 1], ...
                'LineWidth', 2.0);
            
            % =========================================================
            % HIGHLIGHTED WELL SECTION
            % =========================================================
            
            % Highlight the first portion of the trajectory in blue.
            %
            % This gives the plot the same visual effect as the
            % reference image, where part of the wellpath is emphasized.
            
            highlightCount = min(5, numel(E));
            
            plot3(ax, ...
                E(1:highlightCount), ...
                N(1:highlightCount), ...
                TVD(1:highlightCount), ...
                '-', ...
                'Color', [0 0.45 1], ...
                'LineWidth', 3.0);
            
            % =========================================================
            % CURRENT POSITION MARKER
            % =========================================================
            
            % Current position = first survey station for now
            currentIndex = 1;
            
            plot3(ax, ...
                E(currentIndex), ...
                N(currentIndex), ...
                TVD(currentIndex), ...
                'o', ...
                'MarkerSize', 9, ...
                'MarkerFaceColor', [1 0 0], ...
                'MarkerEdgeColor', [1 0 0]);
            
            % =========================================================
            % SURFACE MARKER
            % =========================================================
            
            plot3(ax, ...
                E(1), ...
                N(1), ...
                TVD(1), ...
                'o', ...
                'MarkerSize', 5, ...
                'MarkerFaceColor', [1 1 1], ...
                'MarkerEdgeColor', [1 1 1]);
            
            % =========================================================
            % AXIS LABELS
            % =========================================================
            
            xlabel(ax, 'Easting (ft)');
            ylabel(ax, 'Northing (ft)');
            zlabel(ax, 'TVD (ft)');
            
            % =========================================================
            % TITLE
            % =========================================================
            
            title(ax, ...
                sprintf('MD = %.0f ft | Wellpath', ...
                trajectory.MD_ft(currentIndex)), ...
                'Color', [1 1 1]);
            
            % =========================================================
            % 3D VIEW
            % =========================================================
            
            view(ax, 3);
            
            axis(ax, 'equal');
            
            % Give the plot some breathing room
            axis(ax, 'tight');
            
            % =========================================================
            % INTERACTIVE 3D ROTATION
            % =========================================================
            
            rotate3d(ax, 'on');
            
            hold(ax, 'off');
        
        
            % ---------------------------------------------------------
            % 7. Enable interactive 3D rotation
            % ---------------------------------------------------------
            rotate3d(ax, 'on');
        
        
            % ---------------------------------------------------------
            % 8. Update status
            % ---------------------------------------------------------
            app.TrajectoryStatusValueLabel.Text = ...
                sprintf('Wellpath calculated successfully — %d stations.', ...
                height(trajectory));
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if isempty(app.Trajectory)
                uialert(app.UIFigure, ...
                    'Calculate the wellpath first (Tab: Wellpath).', ...
                    'No Trajectory');
                return
            end
            
            app.TDResults = computeTorqueAndDrag( ...
                app.Trajectory, ...
                app.BaseParams.wAir_lbft, ...
                app.CaseDef.MW_ppg, ...
                app.CaseDef.mu, ...
                app.BaseParams.rEff_ft, ...
                app.BaseParams.steelDensity_ppg);
            
            updateTorqueDragPlots(app);
        end

        % Button pushed function: RunStageButton
        function RunStageButtonPushed(app, event)
            % ============================================================
            % CHECK TELEMETRY
            % ============================================================
        
            if isempty(app.Telemetry)
        
                uialert(app.UIFigure, ...
                    'Telemetry has not been initialized.', ...
                    'No Telemetry');
        
                return
            end
        
        
            % ============================================================
            % GET TELEMETRY UP TO CURRENT STAGE
            % ============================================================
        
            stageTelemetry = getTelemetrySlice( ...
                app.Telemetry, ...
                app.CurrentStage);
        
        
            % ============================================================
            % RUN QC FOR CURRENT STAGE
            % ============================================================
        
            newQCRow = runQCForStage( ...
                app.Telemetry, ...
                app.CurrentStage, ...
                app.BaseParams, ...
                app.BaseParams);
        
        
            % ============================================================
            % STORE QC RESULT
            %
            % IMPORTANT:
            % Do NOT replace app.QCResults completely.
            % Add/update the current stage instead.
            % ============================================================
        
            currentStage = app.CurrentStage;
        
            existingQCRow = app.QCResults.Stage == currentStage;
        
            if any(existingQCRow)
        
                % Replace the existing result for this stage
                app.QCResults(existingQCRow, :) = newQCRow;
        
            else
        
                % Add a new stage result
                app.QCResults = [app.QCResults; newQCRow];
        
            end
        
        
            % ============================================================
            % UPDATE QC & DECISION DISPLAY
            % ============================================================
        
            updateQCDecisionDisplay(app);
        
        
            % ============================================================
            % UPDATE CURRENT STAGE LABEL
            % ============================================================
        
            app.CurrentStageLabel.Text = sprintf( ...
                'Current Stage: %d of 9', ...
                app.CurrentStage);
        
        
            % ============================================================
            % MECHANICAL TRENDS
            % ============================================================
        
            cla(app.MechanicalTrendAxes);
        
            % Hook Load - left Y-axis
            yyaxis(app.MechanicalTrendAxes, 'left');
        
            plot(app.MechanicalTrendAxes, ...
                stageTelemetry.Stage, ...
                stageTelemetry.HookLoad_kips, ...
                '-o', ...
                'LineWidth', 1.5);
        
            ylabel(app.MechanicalTrendAxes, ...
                'Hook Load (kips)');
        
        
            % Torque - right Y-axis
            yyaxis(app.MechanicalTrendAxes, 'right');
        
            plot(app.MechanicalTrendAxes, ...
                stageTelemetry.Stage, ...
                stageTelemetry.Torque_ftlbf, ...
                '-s', ...
                'LineWidth', 1.5);
        
            ylabel(app.MechanicalTrendAxes, ...
                'Torque (ft-lbf)');
        
        
            % ============================================================
            % TORQUE AXIS RANGE
            % ============================================================
        
            torqueMin = min(stageTelemetry.Torque_ftlbf);
            torqueMax = max(stageTelemetry.Torque_ftlbf);
        
            if torqueMin == torqueMax
        
                margin = max(100, ...
                    0.02 * abs(torqueMin));
        
                ylim(app.MechanicalTrendAxes, ...
                    [torqueMin - margin, ...
                     torqueMax + margin]);
        
            else
        
                margin = 0.05 * ...
                    (torqueMax - torqueMin);
        
                ylim(app.MechanicalTrendAxes, ...
                    [torqueMin - margin, ...
                     torqueMax + margin]);
        
            end
        
        
            xlabel(app.MechanicalTrendAxes, ...
                'Stage');
        
            title(app.MechanicalTrendAxes, ...
                sprintf('Mechanical Trends — Stage %d', ...
                app.CurrentStage));
        
            grid(app.MechanicalTrendAxes, 'on');
        
        
            % ============================================================
            % OPERATIONAL TRENDS
            % ============================================================
        
            cla(app.OperationalTrendAxes);
        
            plot(app.OperationalTrendAxes, ...
                stageTelemetry.Stage, ...
                stageTelemetry.SPP_psi, ...
                '-o', ...
                'LineWidth', 1.5);
        
            xlabel(app.OperationalTrendAxes, ...
                'Stage');
        
            ylabel(app.OperationalTrendAxes, ...
                'Standpipe Pressure (psi)');
        
            title(app.OperationalTrendAxes, ...
                sprintf('Operational Trends — Stage %d', ...
                app.CurrentStage));
        
            grid(app.OperationalTrendAxes, 'on');
        end

        % Button pushed function: ConfirmStageButton
        function ConfirmStageButtonPushed(app, event)
            % Get the six answers from the UI
            whatChanged = app.WhatChangedTextArea.Value;
            controllingFactor = app.ControllingFactorTextArea.Value;
            assignedStatus = app.AssignedStatusTextArea.Value;
            uncertainty = app.UncertaintyTextArea.Value;
            recommendedAction = app.RecommendedActionTextArea.Value;
            evidenceNeededNext = app.EvidenceNeededNextTextArea.Value;
            
            % Build the decision row
            decisionRow = buildStageRationale( ...
                app.CurrentStage, ...
                whatChanged, ...
                controllingFactor, ...
                assignedStatus, ...
                uncertainty, ...
                recommendedAction, ...
                evidenceNeededNext);
            
            % Stop if one or more answers are missing
            if ~decisionRow.Confirmed
                uialert(app.UIFigure, ...
                    'All six decision-rationale questions must be answered before advancing.', ...
                    'Incomplete Decision');
                return
            end
            
            % Store/update the decision for this stage
            stageIdx = app.DecisionLog.Stage == app.CurrentStage;
            
            if any(stageIdx)
                app.DecisionLog(stageIdx, :) = decisionRow;
            else
                app.DecisionLog = [app.DecisionLog; decisionRow];
            end
            
            % Update the status display
            app.DecisionStatusLabel.Text = sprintf( ...
                'Decision Status: STAGE %d CONFIRMED', ...
                app.CurrentStage);
            
            % Enable Next Stage
            app.NextStageButton.Enable = 'on';
            
            % Update the decision log table
            app.DecisionLogTable.Data = app.DecisionLog;
        end

        % Button pushed function: NextStageButton
        function NextStageButtonPushed(app, event)
           [canAdvance, msg] = canAdvanceStage( ...
                app.DecisionLog, ...
                app.CurrentStage);
            
            if ~canAdvance
                uialert(app.UIFigure, msg, 'Stage Gate');
                return
            end
            
            % Do not advance beyond Stage 9
            if app.CurrentStage >= 9
                uialert(app.UIFigure, ...
                    'Stage 9 is the final stage.', ...
                    'Final Stage');
                return
            end
            
            % Advance
            app.CurrentStage = app.CurrentStage + 1;
            
            % Update stage label
            app.CurrentDecisionStageLabel.Text = sprintf( ...
                'Stage %d of 9 — Decision Rationale', ...
                app.CurrentStage);
            
            app.CurrentStageLabel.Text = sprintf( ...
                'Current Stage: %d of 9', ...
                app.CurrentStage);
            
            % Reset the six text areas for the new stage
            app.WhatChangedTextArea.Value = {''};
            app.ControllingFactorTextArea.Value = {''};
            app.AssignedStatusTextArea.Value = {''};
            app.UncertaintyTextArea.Value = {''};
            app.RecommendedActionTextArea.Value = {''};
            app.EvidenceNeededNextTextArea.Value = {''};
            
            % Reset decision status
            app.DecisionStatusLabel.Text = sprintf( ...
                'Decision Status: STAGE %d NOT CONFIRMED', ...
                app.CurrentStage);
            
            % Disable Next Stage until this stage is confirmed
            app.NextStageButton.Enable = 'off';
        end

        % Button pushed function: RefreshAuditButton
        function RefreshAuditButtonPushed(app, event)
                % Refresh the final audit display
                updateAuditDisplay( ...
                    app, ...
                    app.QCResults, ...
                    app.DecisionLog, ...
                    app.CurrentStage);
        end

        % Button pushed function: ExportFullReportButton
        function ExportFullReportButtonPushed(app, event)
            % Export the complete examination record
            exportFullReport( ...
                app, ...
                app.QCResults, ...
                app.DecisionLog, ...
                app.CaseDef, ...
                app.CurrentStage);
        end

        % Button pushed function: ExportQCButton
        function ExportQCButtonPushed(app, event)
            % Export QC results
            exportQCResults(app, app.QCResults);
        end

        % Button pushed function: ExportDecisionLogButton
        function ExportDecisionLogButtonPushed(app, event)
            % Export stage decision log
            exportDecisionLog(app, app.DecisionLog);
        end

        % Button pushed function: RunSensitivityButton
        function RunSensitivityButtonPushed(app, event)
            % ============================================================
            % CHECK REQUIRED DATA
            % ============================================================
            
            if isempty(app.Trajectory)
            
                uialert(app.UIFigure, ...
                    'Trajectory has not been calculated yet.', ...
                    'No Trajectory');
            
                return
            
            end
            
            
            if isempty(app.BaseParams)
            
                uialert(app.UIFigure, ...
                    'Base parameters have not been initialized.', ...
                    'No Parameters');
            
                return
            
            end
            
            
            if isempty(app.CaseDef)
            
                uialert(app.UIFigure, ...
                    'Case definition has not been initialized.', ...
                    'No Case Definition');
            
                return
            
            end
            
            
            % ============================================================
            % RUN FRICTION SENSITIVITY
            % ============================================================
            
            try
            
                sensitivity = runFrictionSensitivity( ...
                    app.BaseParams.muSensitivity, ...
                    app.Trajectory, ...
                    app.BaseParams.wAir_lbft, ...
                    app.CaseDef.MW_ppg, ...
                    app.BaseParams.rEff_ft, ...
                    app.BaseParams.steelDensity_ppg);
            
            
                % ========================================================
                % DISPLAY FRICTION SENSITIVITY RESULTS
                % ========================================================
            
                sensitivityTable = table( ...
                    sensitivity.muList(:), ...
                    sensitivity.pickup_kips(:), ...
                    sensitivity.slackoff_kips(:), ...
                    sensitivity.drag_lb(:), ...
                    sensitivity.torque_ftlbf(:), ...
                    'VariableNames', { ...
                    'Mu', ...
                    'Pickup_kips', ...
                    'Slackoff_kips', ...
                    'Drag_lb', ...
                    'Torque_ftlbf'});
            
            
                app.SensitivityResultsTable.Data = sensitivityTable;
            
                app.SensitivityResultsTable.ColumnName = { ...
                    'Friction Factor', ...
                    'Pickup (kips)', ...
                    'Slack-off (kips)', ...
                    'Drag (lb)', ...
                    'Torque (ft-lbf)'};
            
            
                % ========================================================
                % FRICTION LOAD GRAPH
                % ========================================================
            
                cla(app.FrictionLoadAxes);
            
                plot(app.FrictionLoadAxes, ...
                    sensitivity.muList, ...
                    sensitivity.pickup_kips, ...
                    '-o', ...
                    'LineWidth', 1.5);
            
                hold(app.FrictionLoadAxes, 'on');
            
                plot(app.FrictionLoadAxes, ...
                    sensitivity.muList, ...
                    sensitivity.slackoff_kips, ...
                    '-s', ...
                    'LineWidth', 1.5);
            
                hold(app.FrictionLoadAxes, 'off');
            
                xlabel(app.FrictionLoadAxes, ...
                    'Friction Factor, \mu');
            
                ylabel(app.FrictionLoadAxes, ...
                    'Surface Load (kips)');
            
                title(app.FrictionLoadAxes, ...
                    'Friction Factor vs Surface Load');
            
                legend(app.FrictionLoadAxes, ...
                    {'Pickup', 'Slack-off'}, ...
                    'Location', 'best');
            
                grid(app.FrictionLoadAxes, 'on');
            
            
                % ========================================================
                % FRICTION DRAG / TORQUE GRAPH
                % ========================================================
            
                cla(app.FrictionTorqueAxes);
            
                yyaxis(app.FrictionTorqueAxes, 'left');
            
                plot(app.FrictionTorqueAxes, ...
                    sensitivity.muList, ...
                    sensitivity.drag_lb, ...
                    '-o', ...
                    'LineWidth', 1.5);
            
                ylabel(app.FrictionTorqueAxes, ...
                    'Drag (lb)');
            
                yyaxis(app.FrictionTorqueAxes, 'right');
            
                plot(app.FrictionTorqueAxes, ...
                    sensitivity.muList, ...
                    sensitivity.torque_ftlbf, ...
                    '-s', ...
                    'LineWidth', 1.5);
            
                ylabel(app.FrictionTorqueAxes, ...
                    'Torque (ft-lbf)');
            
                xlabel(app.FrictionTorqueAxes, ...
                    'Friction Factor, \mu');
            
                title(app.FrictionTorqueAxes, ...
                    'Friction Factor vs Drag and Torque');
            
                grid(app.FrictionTorqueAxes, 'on');
            
            
                % ============================================================
                % RUN +5% STRING WEIGHT UNCERTAINTY CASE
                % ============================================================
            
                uncertainty = runUncertaintyCase( ...
                    '+5% string weight', ...
                    app.Trajectory, ...
                    app.BaseParams.wAir_lbft, ...
                    app.CaseDef.MW_ppg, ...
                    app.CaseDef.mu, ...
                    app.BaseParams.rEff_ft, ...
                    app.BaseParams.steelDensity_ppg);
            
            
                % ========================================================
                % BUILD UNCERTAINTY TABLE
                % ========================================================
            
                quantities = { ...
                    'Pickup Load (kips)'
                    'Slack-off Load (kips)'
                    'Drag (lb)'
                    'Torque (ft-lbf)'};
            
            
                baseline = [ ...
                    uncertainty.baseline.pickup_kips
                    uncertainty.baseline.slackoff_kips
                    uncertainty.baseline.drag_lb
                    uncertainty.baseline.torque_ftlbf];
            
            
                modified = [ ...
                    uncertainty.modified.pickup_kips
                    uncertainty.modified.slackoff_kips
                    uncertainty.modified.drag_lb
                    uncertainty.modified.torque_ftlbf];
            
            
                change = modified - baseline;
            
            
                uncertaintyTable = table( ...
                    string(quantities), ...
                    baseline, ...
                    modified, ...
                    change, ...
                    'VariableNames', { ...
                    'Quantity', ...
                    'Baseline', ...
                    'Plus5PercentStringWeight', ...
                    'Change'});
            
            
                app.UncertaintyResultsTable.Data = uncertaintyTable;
            
                app.UncertaintyResultsTable.ColumnName = { ...
                    'Quantity', ...
                    'Baseline', ...
                    '+5% String Weight', ...
                    'Change'};
            
            
                % ============================================================
                % ENGINEERING INTERPRETATION
                % ============================================================
            
                muLow = sensitivity.muList(1);
                muHigh = sensitivity.muList(end);
            
                pickupChange = ...
                    sensitivity.pickup_kips(end) - ...
                    sensitivity.pickup_kips(1);
            
                dragChange = ...
                    sensitivity.drag_lb(end) - ...
                    sensitivity.drag_lb(1);
            
                torqueChange = ...
                    sensitivity.torque_ftlbf(end) - ...
                    sensitivity.torque_ftlbf(1);
            
                slackoffChange = ...
                    sensitivity.slackoff_kips(end) - ...
                    sensitivity.slackoff_kips(1);
            
            
                interpretation = { ...
                    'FRICTION SENSITIVITY INTERPRETATION'
                    ''
                    sprintf('Friction factor was varied from %.2f to %.2f.', ...
                    muLow, muHigh)
                    ''
                    sprintf('Pickup load changed by %+.2f kips.', ...
                    pickupChange)
                    sprintf('Slack-off load changed by %+.2f kips.', ...
                    slackoffChange)
                    sprintf('Drag changed by %+.1f lb.', ...
                    dragChange)
                    sprintf('Torque changed by %+.1f ft-lbf.', ...
                    torqueChange)
                    ''
                    'Increasing friction increases pickup load, drag and torque.'
                    'Increasing friction reduces slack-off load because friction'
                    'opposes downward movement.'
                    ''
                    'The friction-factor sweep therefore quantifies the sensitivity'
                    'of the torque-and-drag predictions to uncertainty in friction.'
                    ''
                    '+5% string-weight uncertainty was also evaluated separately.'
                    sprintf('Pickup change: %+.2f kips.', ...
                    uncertainty.modified.pickup_kips - ...
                    uncertainty.baseline.pickup_kips)
                    sprintf('Drag change: %+.1f lb.', ...
                    uncertainty.modified.drag_lb - ...
                    uncertainty.baseline.drag_lb)
                    };
            
            
                app.SensitivityInterpretationTextArea.Value = interpretation;
            
            
                % ============================================================
                % SUCCESS MESSAGE
                % ============================================================
            
                uialert(app.UIFigure, ...
                    'Friction sensitivity and uncertainty analysis completed successfully.', ...
                    'Sensitivity Complete');
            
            
            catch ME
            
                uialert(app.UIFigure, ...
                    sprintf(['Sensitivity analysis failed.\n\n' ...
                             '%s'], ME.message), ...
                    'Sensitivity Error');
            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1_CaseDefinition = uitab(app.TabGroup);
            app.Tab1_CaseDefinition.Title = 'Case Definition';
            app.SerialNumberEditFieldLabel = uilabel(app.Tab1_CaseDefinition);
            app.SerialNumberEditFieldLabel.Text = 'Student Serial Number';
            app.SerialNumberEditField = uieditfield(app.Tab1_CaseDefinition, 'numeric');
            app.MudWeightLabel = uilabel(app.Tab1_CaseDefinition);
            app.MudWeightLabel.Text = 'Mud Weight';
            app.FrictionFactorLabel = uilabel(app.Tab1_CaseDefinition);
            app.FrictionFactorLabel.Text = 'Friction Factor';
            app.EquipmentLimitsLabel = uilabel(app.Tab1_CaseDefinition);
            app.EquipmentLimitsLabel.Text = 'Equipment Limits';
            app.HookLoadOffsetLabel = uilabel(app.Tab1_CaseDefinition);
            app.HookLoadOffsetLabel.Text = 'Hook Load Offset';
            app.AssumptionsTextAreaLabel = uilabel(app.Tab1_CaseDefinition);
            app.AssumptionsTextAreaLabel.Text = 'Assumptions';
            app.MudWeightValueLabel = uilabel(app.Tab1_CaseDefinition);
            app.MudWeightValueLabel.Text = '--';
            app.FrictionFactorValueLabel = uilabel(app.Tab1_CaseDefinition);
            app.FrictionFactorValueLabel.Text = '--';
            app.HookLoadOffsetValueLabel = uilabel(app.Tab1_CaseDefinition);
            app.HookLoadOffsetValueLabel.Text = '--';
            app.EquipmentLimitsTextArea = uitextarea(app.Tab1_CaseDefinition);
            app.EquipmentLimitsTextArea.Value = {'--'};
            app.AssumptionsTextArea = uitextarea(app.Tab1_CaseDefinition);
            app.AssumptionsTextArea.Value = {'--'};
            app.Tab2_SurveyValidation = uitab(app.TabGroup);
            app.Tab2_SurveyValidation.Title = 'Survey & Validation';
            app.LoadSurveyButton = uibutton(app.Tab2_SurveyValidation, 'push');
            app.LoadSurveyButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSurveyButtonPushed, true);
            app.LoadSurveyButton.Text = 'Load Examination Survey';
            app.ValidateSurveyButton = uibutton(app.Tab2_SurveyValidation, 'push');
            app.ValidateSurveyButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateSurveyButtonPushed, true);
            app.ValidateSurveyButton.Text = 'Validate Survey';
            app.SurveyTable = uitable(app.Tab2_SurveyValidation);
            app.SurveyTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SurveyTable.RowName = {};
            app.ValidationStatusLabel = uilabel(app.Tab2_SurveyValidation);
            app.ValidationStatusLabel.Text = 'Validation Status';
            app.ValidationLogTextArea = uitextarea(app.Tab2_SurveyValidation);
            app.ValidationLogTextArea.Value = {'--'};
            app.SurveyDataLabel = uilabel(app.Tab2_SurveyValidation);
            app.SurveyDataLabel.Text = 'Survey Data';
            app.Tab3_Wellpath = uitab(app.TabGroup);
            app.Tab3_Wellpath.Title = 'Wellpath ';
            app.Wellpath3DUIAxes = uiaxes(app.Tab3_Wellpath);
            title(app.Wellpath3DUIAxes, '3D Wellpath')
            xlabel(app.Wellpath3DUIAxes, 'X')
            ylabel(app.Wellpath3DUIAxes, 'Y')
            zlabel(app.Wellpath3DUIAxes, 'Z')
            app.CalculateWellpathButton = uibutton(app.Tab3_Wellpath, 'push');
            app.CalculateWellpathButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateWellpathButtonPushed, true);
            app.CalculateWellpathButton.Text = 'Calculate Wellpath';
            app.TrajectoryStatusLabel = uilabel(app.Tab3_Wellpath);
            app.TrajectoryStatusLabel.Text = 'Trajectory Status';
            app.TrajectoryStatusValueLabel = uilabel(app.Tab3_Wellpath);
            app.TrajectoryStatusValueLabel.Text = '--';
            app.TrajectoryTable = uitable(app.Tab3_Wellpath);
            app.TrajectoryTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TrajectoryTable.RowName = {};
            app.Tab4_TorqueDrag = uitab(app.TabGroup);
            app.Tab4_TorqueDrag.Title = 'Torque & Drag';
            app.PickupSlackoffAxes = uiaxes(app.Tab4_TorqueDrag);
            title(app.PickupSlackoffAxes, 'Pickup / Slack-off Hook Load')
            xlabel(app.PickupSlackoffAxes, 'X')
            ylabel(app.PickupSlackoffAxes, 'Y')
            zlabel(app.PickupSlackoffAxes, 'Z')
            app.DragTorqueAxes = uiaxes(app.Tab4_TorqueDrag);
            title(app.DragTorqueAxes, 'Cumulative Drag & Torque')
            xlabel(app.DragTorqueAxes, 'X')
            ylabel(app.DragTorqueAxes, 'Y')
            zlabel(app.DragTorqueAxes, 'Z')
            app.CalculateButton = uibutton(app.Tab4_TorqueDrag, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate Torque & Drag';
            app.SurfaceValuesLabel = uilabel(app.Tab4_TorqueDrag);
            app.SurfaceValuesLabel.Text = 'Surface values will appear after calculation.';
            app.Tab5_TelemetryDashboard = uitab(app.TabGroup);
            app.Tab5_TelemetryDashboard.Title = 'Telemetry ';
            app.MechanicalTrendAxes = uiaxes(app.Tab5_TelemetryDashboard);
            title(app.MechanicalTrendAxes, 'Mechanical Trends')
            xlabel(app.MechanicalTrendAxes, 'X')
            ylabel(app.MechanicalTrendAxes, 'Y')
            zlabel(app.MechanicalTrendAxes, 'Z')
            app.OperationalTrendAxes = uiaxes(app.Tab5_TelemetryDashboard);
            title(app.OperationalTrendAxes, 'Operational Trends')
            xlabel(app.OperationalTrendAxes, 'X')
            ylabel(app.OperationalTrendAxes, 'Y')
            zlabel(app.OperationalTrendAxes, 'Z')
            app.CurrentStageLabel = uilabel(app.Tab5_TelemetryDashboard);
            app.CurrentStageLabel.Text = 'Current Stage: 1 of 9';
            app.RunStageButton = uibutton(app.Tab5_TelemetryDashboard, 'push');
            app.RunStageButton.ButtonPushedFcn = createCallbackFcn(app, @RunStageButtonPushed, true);
            app.RunStageButton.Text = 'Run Stage';
            app.Tab6_QCDecision = uitab(app.TabGroup);
            app.Tab6_QCDecision.Title = 'QC & Decision';
            app.UtilizationBarAxes = uiaxes(app.Tab6_QCDecision);
            title(app.UtilizationBarAxes, 'Equipment Utilization')
            xlabel(app.UtilizationBarAxes, 'Equipment')
            ylabel(app.UtilizationBarAxes, 'Utilization (%)')
            zlabel(app.UtilizationBarAxes, 'Z')
            app.StatusLampLabel = uilabel(app.Tab6_QCDecision);
            app.StatusLampLabel.Text = 'QC Status';
            app.StatusLamp = uilamp(app.Tab6_QCDecision);
            app.ControllingTriggerLabel = uilabel(app.Tab6_QCDecision);
            app.ControllingTriggerLabel.Text = 'Controlling Trigger:';
            app.QCResultsTable = uitable(app.Tab6_QCDecision);
            app.QCResultsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.QCResultsTable.RowName = {};
            app.Tab7_StageDecisionLog = uitab(app.TabGroup);
            app.Tab7_StageDecisionLog.Title = 'Stage Decision Log';
            app.CurrentDecisionStageLabel = uilabel(app.Tab7_StageDecisionLog);
            app.CurrentDecisionStageLabel.Text = 'Stage 1 of 9 — Decision Rationale';
            app.WhatchangedLabel = uilabel(app.Tab7_StageDecisionLog);
            app.WhatchangedLabel.Text = 'What changed?';
            app.WhatChangedTextArea = uitextarea(app.Tab7_StageDecisionLog);
            app.ControllingFactorTextArea = uitextarea(app.Tab7_StageDecisionLog);
            app.WhichcalculationmeasurementcontrolstheriskLabel = uilabel(app.Tab7_StageDecisionLog);
            app.WhichcalculationmeasurementcontrolstheriskLabel.Text = 'Which calculation/measurement controls the risk?';
            app.AssignedStatusTextArea = uitextarea(app.Tab7_StageDecisionLog);
            app.WhatstatusdoyouassignandwhyLabel = uilabel(app.Tab7_StageDecisionLog);
            app.WhatstatusdoyouassignandwhyLabel.Text = 'What status do you assign, and why?';
            app.UncertaintyTextArea = uitextarea(app.Tab7_StageDecisionLog);
            app.WhatuncertaintyremainsLabel = uilabel(app.Tab7_StageDecisionLog);
            app.WhatuncertaintyremainsLabel.Text = 'What uncertainty remains?';
            app.RecommendedActionTextArea = uitextarea(app.Tab7_StageDecisionLog);
            app.WhatactionescalationdoyourecommendLabel = uilabel(app.Tab7_StageDecisionLog);
            app.WhatactionescalationdoyourecommendLabel.Text = 'What action/escalation do you recommend?';
            app.EvidenceNeededNextTextArea = uitextarea(app.Tab7_StageDecisionLog);
            app.WhatevidencedoyourequirenextLabel = uilabel(app.Tab7_StageDecisionLog);
            app.WhatevidencedoyourequirenextLabel.Text = 'What evidence do you require next?';
            app.ConfirmStageButton = uibutton(app.Tab7_StageDecisionLog, 'push');
            app.ConfirmStageButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmStageButtonPushed, true);
            app.ConfirmStageButton.Text = 'Confirm Stage Decision';
            app.DecisionStatusLabel = uilabel(app.Tab7_StageDecisionLog);
            app.DecisionStatusLabel.Text = 'Decision Status: NOT CONFIRMED';
            app.NextStageButton = uibutton(app.Tab7_StageDecisionLog, 'push');
            app.NextStageButton.ButtonPushedFcn = createCallbackFcn(app, @NextStageButtonPushed, true);
            app.NextStageButton.Text = 'Next Stage';
            app.DecisionLogTable = uitable(app.Tab7_StageDecisionLog);
            app.DecisionLogTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.DecisionLogTable.RowName = {};
            app.Tab8_Sensitivity = uitab(app.TabGroup);
            app.Tab8_Sensitivity.Title = 'Sensitivity & Uncertainty';
            app.FrictionLoadAxes = uiaxes(app.Tab8_Sensitivity);
            title(app.FrictionLoadAxes, 'μ vs Pickup / Slack-off')
            xlabel(app.FrictionLoadAxes, 'μ')
            ylabel(app.FrictionLoadAxes, 'Pickup / Slack-off')
            zlabel(app.FrictionLoadAxes, 'Z')
            app.FrictionTorqueAxes = uiaxes(app.Tab8_Sensitivity);
            title(app.FrictionTorqueAxes, 'μ vs Drag / Torque')
            xlabel(app.FrictionTorqueAxes, 'μ ')
            ylabel(app.FrictionTorqueAxes, 'Drag / Torque')
            zlabel(app.FrictionTorqueAxes, 'Z')
            app.SensitivityTitleLabel = uilabel(app.Tab8_Sensitivity);
            app.SensitivityTitleLabel.Text = 'FRICTION SENSITIVITY & UNCERTAINTY ANALYSIS';
            app.RunSensitivityButton = uibutton(app.Tab8_Sensitivity, 'push');
            app.RunSensitivityButton.ButtonPushedFcn = createCallbackFcn(app, @RunSensitivityButtonPushed, true);
            app.RunSensitivityButton.Text = 'Run Sensitivity Analysis';
            app.SensitivityResultsTable = uitable(app.Tab8_Sensitivity);
            app.SensitivityResultsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SensitivityResultsTable.RowName = {};
            app.UncertaintyResultsTable = uitable(app.Tab8_Sensitivity);
            app.UncertaintyResultsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UncertaintyResultsTable.RowName = {};
            app.SensitivityInterpretationTextArea = uitextarea(app.Tab8_Sensitivity);
            app.EngineeringInterpretationLabel = uilabel(app.Tab8_Sensitivity);
            app.EngineeringInterpretationLabel.Text = 'Engineering Interpretation';
            app.AdditionalUncertainty5StringWeightLabel = uilabel(app.Tab8_Sensitivity);
            app.AdditionalUncertainty5StringWeightLabel.Text = 'Additional Uncertainty (+5% String Weight) ';
            app.Tab9_ExportAudit = uitab(app.TabGroup);
            app.Tab9_ExportAudit.Title = 'Export & Audit';
            app.ExportAuditTitleLabel = uilabel(app.Tab9_ExportAudit);
            app.ExportAuditTitleLabel.Text = 'Export & Audit — Final Examination Record';
            app.AuditStatusLabel = uilabel(app.Tab9_ExportAudit);
            app.AuditStatusLabel.Text = 'Audit Status: INCOMPLETE';
            app.AuditResultsTable = uitable(app.Tab9_ExportAudit);
            app.AuditResultsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AuditResultsTable.RowName = {};
            app.RefreshAuditButton = uibutton(app.Tab9_ExportAudit, 'push');
            app.RefreshAuditButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshAuditButtonPushed, true);
            app.RefreshAuditButton.Text = 'Refresh Audit';
            app.ExportFullReportButton = uibutton(app.Tab9_ExportAudit, 'push');
            app.ExportFullReportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFullReportButtonPushed, true);
            app.ExportFullReportButton.Text = 'Export Full Report';
            app.ExportQCButton = uibutton(app.Tab9_ExportAudit, 'push');
            app.ExportQCButton.ButtonPushedFcn = createCallbackFcn(app, @ExportQCButtonPushed, true);
            app.ExportQCButton.Text = 'Export QC Results';
            app.ExportDecisionLogButton = uibutton(app.Tab9_ExportAudit, 'push');
            app.ExportDecisionLogButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDecisionLogButtonPushed, true);
            app.ExportDecisionLogButton.Text = 'Export Decision Log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PGG327FinalExam

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end